classdef tutorial_edit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadandSavePanel               matlab.ui.container.Panel
        SaveNetworkandQuitButton       matlab.ui.control.Button
        CurrentConfigFileEditField     matlab.ui.control.EditField
        CurrentConfigFileEditFieldLabel  matlab.ui.control.Label
        SelectNetworkConfigFileButton  matlab.ui.control.Button
        TabGroup2                      matlab.ui.container.TabGroup
        NetworkTab                     matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        SingleCellTab                  matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        NodesTab                       matlab.ui.container.Tab
        EditNodePanel                  matlab.ui.container.Panel
        NodeNumberEditField            matlab.ui.control.EditField
        NodeNumberEditFieldLabel       matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        AttributeValueEditField        matlab.ui.control.NumericEditField
        AttributeValueEditFieldLabel   matlab.ui.control.Label
        UpdateButton                   matlab.ui.control.Button
        AttributeDropDown              matlab.ui.control.DropDown
        AttributeDropDownLabel         matlab.ui.control.Label
        SelectNodeButton_2             matlab.ui.control.Button
        NodeDeletionPanel              matlab.ui.container.Panel
        DeleteNodeButton               matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        SelectNodeButton               matlab.ui.control.Button
        NodeCreationPanel              matlab.ui.container.Panel
        NodeNameEditField              matlab.ui.control.EditField
        NodeNameEditFieldLabel         matlab.ui.control.Label
        CancelButton_2                 matlab.ui.control.Button
        SaveNodeButton                 matlab.ui.control.Button
        NodePresetsDropDown            matlab.ui.control.DropDown
        NodePresetsDropDownLabel       matlab.ui.control.Label
        NodeTypeDropDown               matlab.ui.control.DropDown
        NodeTypeDropDownLabel          matlab.ui.control.Label
        SelectLocationButton           matlab.ui.control.Button
        PathsTab                       matlab.ui.container.Tab
        Button                         matlab.ui.control.Button
        ProbesTab                      matlab.ui.container.Tab
    end

    
    properties (Access = private)
        newnodebool % Boolean for creating a node
        delnodebool % Boolean for deleting a node
        editnodebool % Boolean for editing a node
        tooltipObj % object that corresponds to the node tooltip
        editnodeind % index for node to edit
        delnodeind % index for node to delete
        node1ind % index for path creation node i
        node2ind % index for path creation node j
        nodeTypesAttr % attributes which correspond to each unique cell type
        Node_pos % The coordinates of the nodes loaded in
        node_connections % Linkages for node arrays
        path_plot % physical lines corresponding to the paths in the model
        path_connections % linkages for path arrays
        node_pos_plot % scatter plot for the nodes
        probe_pos_plot % scatter plot for the probes
        Probe_pos % The coordinates for the probes loaded in
        raw_nodes % node attributes the full network things
        raw_nodes_reserve % reserve for going to original settings
        raw_paths % path attributes the full network things
        raw_paths_reserve % reserve for going to original settings
        raw_probes % probe attributes for the network
        node_presets % node presets for creating a new node
        path_presets % path presets for creating a new path
        unique_node_idx % indexing corresponding with the node presets
        unique_path_idx % indexing corresponding with the path presets
        nodeattind % index for editing the node attributes
        pathattind % index for editing the path attributes
    end    
    
    methods (Access = private)
        
                
        function d = point_to_line(app, pt, v1, v2)
            % TO ADD: 3D capability functionality (shorten it)
            % Euclidean distance to the midpoint of the line  
            midpoint = [0.5*(v1(1) +v2(1)) 0.5*(v1(2)+v2(2))];
            %midpoint = [0.5*(v1(1) +v2(1)) 0.5*(v1(2)+v2(2)) 0.5*(v1(3)+v2(3))];
            d = pdist([pt;midpoint],'euclidean');
        end
        
        function index = nearest_line(app,pt)
            minimum_val = 1000000;
            if size(app.raw_paths,1) == 0 
                index = 0;
                return
            end
            for i = 2:size(app.raw_paths,1)
                [node1idx,~] = find(app.node_connections.' == app.raw_paths{i,3});
                [node2idx,~] = find(app.node_connections.' == app.raw_paths{i,4});
                curr_dist = app.point_to_line(pt,app.Node_pos(node1idx,1:2),app.Node_pos(node2idx,1:2));
                if curr_dist < minimum_val
                    minimum_val = curr_dist;
                    index = i-1;        
                end
            end
        end
        
        function val = unique_num(app,array)
            B = sort(array);
            for i=1:length(B)
                if i ~= B(i)
                    val = i;
                    return
                end
            end
            val = B(end)+1;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            
            
            hold(app.UIAxes,'on')
            app.node_pos_plot = scatter(app.UIAxes,[],[],75,'filled',...
                'LineWidth',2,'Marker','o','CData',[0 0 0]); % black
            app.probe_pos_plot =scatter(app.UIAxes,[],[],'LineWidth',1.5,'Marker','d','CData',[0 0 0]);
    
            xlsx_file = 'Heart_N3_second.xlsx';
            [~,~,app.raw_nodes]=xlsread(xlsx_file,'Node_second');
            [~,~,app.raw_paths]=xlsread(xlsx_file,'Path_second');
            [~,~,app.raw_probes]=xlsread(xlsx_file,'Probe');
            app.raw_nodes(:,end-2) = [];
            app.raw_nodes(:,end) = [];
            app.raw_nodes(end,:) = [];
            app.raw_nodes_reserve = app.raw_nodes;
            app.raw_paths_reserve = app.raw_paths;
            % TO DEBUG: the dictionary setup is a bit out
            app.nodeTypesAttr = dictionary(["N", "M", "NM"],{app.raw_nodes(1,4:24),app.raw_nodes(1,25:50),app.raw_nodes(1,4:50)});
            node_temp = cell2mat(app.raw_nodes(2:44,3:50));
            node_temp(:,2) = 40000;
            node_temp(:,45:46)=40000;
            node_temp(isnan(node_temp))=40000;
            [app.node_presets,app.unique_node_idx] = unique(node_temp,'rows');
            app.unique_node_idx = app.unique_node_idx +1;

            path_temp = cell2mat(app.raw_paths_reserve(2:55,5:15));
            [app.path_presets,app.unique_path_idx] = unique(path_temp,'rows');
            app.unique_path_idx = app.unique_path_idx +1;

            NumPaths = size(cell2mat(app.raw_paths(2:end,4)),1);
            NumNodes = size(cell2mat(app.raw_nodes(2:end,4)),1);
            app.Node_pos= cell2mat(app.raw_nodes(2:end,end-2:end-1));
            app.Probe_pos= cell2mat(app.raw_probes(2:end,3:4));
            app.UIAxes.XLim = [round(min(app.Node_pos(:,1)),-1)-10 round(max(app.Node_pos(:,1)),-1)+10];
            app.UIAxes.YLim = [round(min(app.Node_pos(:,2)),-1)-10 round(max(app.Node_pos(:,2)),-1)+10];
            % Display the nodes and probes; Store the node and probe positions
            if ~isempty(app.Node_pos)
                app.node_pos_plot.XData = app.Node_pos(:,1);
                app.node_pos_plot.YData = app.Node_pos(:,2);
                %app.node_pos_plot.ZData = app.Node_pos(:,q);
                app.node_pos_plot.CData = zeros(NumNodes,3);
                
                for n=1:NumNodes
                    text(app.UIAxes,app.Node_pos(n,1),app.Node_pos(n,2)+2,int2str(n),'Color','blue','FontSize',12,'tag',sprintf('cell%i',n));
                end
                app.node_connections = 1:NumNodes;
            else
                error('The node position is empty.');
                return;
            end
            if ~isempty(app.Probe_pos)
                app.probe_pos_plot.XData = app.Probe_pos(:,1);
                app.probe_pos_plot.YData = app.Probe_pos(:,2);
                %app.probe_pos_plot.ZData = app.Probe_pos(:,q);
            else
                error('The Probe position is empty.');
                return;
            end
            % Draw the paths 
            app.path_plot = [];
            % IMPORTANT HERE: DETERMINE HOW TO MAKE THE PATHS 3D IF NEEDED AT ALL
            app.path_connections = [1:NumPaths.'; cell2mat(app.raw_paths(2:end,3)).'; cell2mat(app.raw_paths(2:end,4)).'].';
            
            for n=1:NumPaths
                app.path_plot(n)=line([app.Node_pos(app.path_connections(n,2),1),app.Node_pos(app.path_connections(n,3),1)],...
                    [app.Node_pos(app.path_connections(n,2),2),app.Node_pos(app.path_connections(n,3),2)],'LineWidth',1.5,'Color',[0 0 0],...
                    'Parent',app.UIAxes,'tag',sprintf('path%i',n));
            end
            % Create the legend
            legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
            % I = imread('heartoutline.jpg'); 
            % x = [20 180];
            % y = [28 192];
            % I = flipdim(I, 1);
            % h= image(app.UIAxes,x,y,I); 
            % uistack(h,'bottom')
        end

        % Button pushed function: SelectLocationButton
        function selectLocation(app, event)
            app.newnodebool = true;
            app.SelectLocationButton.Enable = "off";
            app.SelectNodeButton.Enable = "off";
            app.SelectNodeButton_2.Enable = "off";
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            cursor = app.UIAxes.CurrentPoint;
            x = cursor(1,1);
            y = cursor(1,2);
            xLimits = app.UIAxes.XLim;
            yLimits = app.UIAxes.YLim;
            delete(app.tooltipObj)
            disp('click')
            % Check that the mouse click is within the Axis
            if (x > min(xLimits) && x < max(xLimits) && ...
                y > min(yLimits) && y < max(yLimits))
                if ~isempty(app.Node_pos)
                    disp('click node find now')
                    [~,dist1] = dsearchn([x,y],app.Node_pos);
                    [~,ind1]=min(dist1);
                    str = append('Node: ',int2str(app.raw_nodes{ind1+1,3}),' = ', app.raw_nodes(ind1+1,1));
                    if strcmp('N',app.raw_nodes(ind1+1,2))
                        str = [str,'Cell Type: N'];
                        back_color = [1 1 .3];
                    elseif strcmp('NM',app.raw_nodes(ind1+1,2))
                        str = [str,'Cell Type: NM'];
                        back_color = [1 .8 .4];
                    elseif strcmp('M',app.raw_nodes(ind1+1,2))
                        str = [str,'Cell Type: M'];
                        back_color = [1 .8 .8];
                    end
                    app.tooltipObj = text(app.UIAxes,x+5,y+5,str,...
                        'backgroundcolor',back_color,'tag','mytooltip','edgecolor',[0 0 0],...
                        'hittest','off');
                else 
                    ind1 = 0;
                    app.newnodebool = false;
                    app.delnodebool = false;
                    app.editnodebool = false;
                end
                if app.newnodebool
                    disp('new node')
                    app.newnodebool = false;
                    app.SelectLocationButton.Enable = "on";
                    app.SelectNodeButton.Enable = "on";
                    app.SelectNodeButton_2.Enable = "on";
                    app.SaveNodeButton.Enable = "on";
                    app.CancelButton_2.Enable = "on";
                    delete(app.tooltipObj)

                    c = get(app.node_pos_plot,'CData');
                    x_temp = get(app.node_pos_plot,'XData');
                    y_temp = get(app.node_pos_plot,'YData');
                    x_temp(end+1) = x;
                    y_temp(end+1) = y;
                    c(end+1,:) = [0 1 0];
                    set(app.node_pos_plot,'XData',x_temp,'YData',y_temp,'CData',c);
                elseif app.delnodebool
                    disp('delete selection')
                    app.delnodebool = false;
                    app.delnodeind = ind1;
                    app.SelectLocationButton.Enable = "on";
                    app.SelectNodeButton.Enable = "on";
                    app.SelectNodeButton_2.Enable = "on";
                    app.DeleteNodeButton.Enable = "on";
                    app.CancelButton.Enable = "on";
                    %set the node information based on nearest node
                    c = get(app.node_pos_plot,'CData');
                    color = [1 0 0];        
                    [q, idx] = ismember(color,c,'rows');
                    if q %app.ind2 previous
                        if idx == app.editnodeind
                            c(idx,:) = [0 0 1];
                        elseif idx == app.node1ind
                            c(idx,:) = [1 1 0];
                        elseif idx == app.node2ind
                            c(idx,:) = [1 0 1];
                        else
                            c(idx,:) = [0 0 0];
                        end
                    end
                    c(ind1,:) = color;
                    set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
                elseif app.editnodebool
                    disp('edit node')
                    app.editnodebool = false;
                    app.editnodeind = ind1;
                    app.nodeattind = 1;
                    app.AttributeDropDown.Items = app.nodeTypesAttr{app.raw_nodes(ind1+1,2)};
                    app.SelectLocationButton.Enable = "on";
                    app.SelectNodeButton.Enable = "on";
                    app.SelectNodeButton_2.Enable = "on";
                    %set the node information based on nearest node
                    c = get(app.node_pos_plot,'CData');
                    color = [0 0 1];        
                    [q, idx] = ismember(color,c,'rows');
                    % Changing the previously selected node if it exists
                    if q 
                        if idx == app.delnodeind
                            c(idx,:) = [1 0 0];
                        elseif idx == app.node1ind
                            c(idx,:) = [1 1 0];
                        elseif idx == app.node2ind
                            c(idx,:) = [1 0 1];
                        else
                            c(idx,:) = [0 0 0];
                        end
                    end
                    c(ind1,:) = color; 
                    set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
                    set(app.NodeNumberEditField,'Value',sprintf('%s (%i)',app.raw_nodes{app.editnodeind+1,1}, ind1));
                    % the following line is for typing, figure out how this
                    % works
                    %set(app.nodetypecurr,'String',app.raw_nodes(app.editnodeind+1,1));
                    % TO CHANGE: use app.AttributeDropDown.Value in a
                    % dictionary sense to find the right value from the
                    % updated edit node selection
                    set(app.AttributeValueEditField,'Value',app.raw_nodes{app.editnodeind+1,app.nodeattind+3});
                end
                drawnow update;
            end
            
        end

        % Button pushed function: SelectNodeButton
        function selectNodeDel(app, event)
            app.delnodebool = true;
            app.SelectLocationButton.Enable = "off";
            app.SelectNodeButton.Enable = "off";
            app.SelectNodeButton_2.Enable = "off";
        end

        % Button pushed function: SelectNodeButton_2
        function selectNodeEdit(app, event)
            app.editnodebool = true;
            app.SelectLocationButton.Enable = "off";
            app.SelectNodeButton.Enable = "off";
            app.SelectNodeButton_2.Enable = "off";
        end

        % Value changed function: NodeTypeDropDown
        function presetChange(app, event)
            value = app.NodeTypeDropDown.Value;
            app.NodePresetsDropDown.Items = app.nodeTypesAttr{value};
        end

        % Button pushed function: SaveNodeButton
        function saveNode(app, event)
            
            app.SaveNodeButton.Enable = "off";
            app.CancelButton_2.Enable = "off";
        end

        % Button pushed function: CancelButton_2
        function cancelNodeSave(app, event)
            
            % Removing the node from the graph
            c = get(app.node_pos_plot,'CData');
            c(end,:) = [];
            set(app.node_pos,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);
            app.SelectLocationButton.Enable = "on";
            app.SaveNodeButton.Enable = "off";
            app.CancelButton_2.Enable = "off";
        end

        % Button pushed function: DeleteNodeButton
        function deleteNode(app, event)
            app.DeleteNodeButton.Enable = "off";
            app.CancelButton.Enable = "off";
        end

        % Button pushed function: CancelButton
        function cancelNodeDel(app, event)
            app.DeleteNodeButton.Enable = "off";
            app.CancelButton.Enable = "off";
        end

        % Button pushed function: SelectNetworkConfigFileButton
        function selectConfigFile(app, event)
            
            % UI window allowing the operator to browse for and select a data file
            [fileName,filePath] = uigetfile('*.mat','Load Heart Configuration File');
            f = fullfile(filePath,fileName);
            app.CurrentConfigFileEditField.Value = fileName;           
            
            dim=load(f);

            % TO ADD: Will need to reset the following
            % app.raw_nodes => dim.Node_para gives the parameter names
            %               => dim.Node_Raw gives the rest below it
            % app.raw_paths => dim.Path_para gives the parameter names
            %               => dim.Path_Raw gives the rest below it
            % app.raw_probes => dim.Probes_raw

            [NumNodes,~]=size(dim.Node);
            [NumPaths,~]=size(dim.Path);
            app.Node_pos=dim.Node_pos;
            app.Probe_pos=dim.Probe_pos;
            % Display the nodes and probes; Store the node and probe positions
            if ~isempty(app.Node_pos)
                c=zeros(NumNodes,3);
                set(app.node_pos_plot,'XData',app.Node_pos(:,1),'YData',app.Node_pos(:,2),'CData',c);%'ZData',app.Node_pos(:,3),'CData',c);
                
                for n=1:NumNodes
                    text(app.UIAxes,app.Node_pos(n,1),app.Node_pos(n,2)+2,int2str(n),'Color','blue','FontSize',12,'tag',sprintf('cell%i',n));
                end
                app.node_connections = 1:NumNodes;
            else
                error('The node position is empty.');
                return;
            end
            if ~isempty(app.Probe_pos)
                set(app.probe_pos_plot,'XData',app.Probe_pos(:,1),'YData',app.Probe_pos(:,2));%,'ZData',app.Probe_pos(:,3));
            else
                error('The Probe position is empty.');
                return;
            end
            % Draw the paths 
            app.path_connections = [1:NumPaths.'; dim.Path(:,1).'; dim.Path(:,2).'].';
            for n=1:NumPaths
                app.path_plot(n)=line([app.Node_pos(app.path_connections(n,1),1),app.Node_pos(app.path_connections(n,2),1)],...
                    [app.Node_pos(app.path_connections(n,1),2),app.Node_pos(app.path_connections(n,2),2)],'LineWidth',1.5,'Color',[0 0 0],...
                    'Parent',app.UIAxes,'tag',sprintf('path%i',n));
            end
            % Create the legend
            legend([app.node_pos_plot,app.probe_pos_plot],{'Node','Probe'});
            I = imread('heartoutline.jpg'); 
            x = [20 180];
            y = [28 192];
            I = flipdim(I, 1);
            h= image(app.UIAxes,x,y,I); 
            uistack(h,'bottom')
            hold on;
        end

        % Button pushed function: SaveNetworkandQuitButton
        function saveNetwork(app, event)
            
        end

        % Value changed function: AttributeDropDown
        function editAttrChange(app, event)
            value = app.AttributeDropDown.Value
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NodesTab = uitab(app.TabGroup);
            app.NodesTab.Title = 'Nodes';
            app.NodeCreationPanel = uipanel(app.NodesTab);
            app.NodeCreationPanel.Title = 'Node Creation';
            app.SelectLocationButton = uibutton(app.NodeCreationPanel, 'push');
            app.SelectLocationButton.ButtonPushedFcn = createCallbackFcn(app, @selectLocation, true);
            app.SelectLocationButton.Text = 'Select Location';
            app.NodeTypeDropDownLabel = uilabel(app.NodeCreationPanel);
            app.NodeTypeDropDownLabel.Text = 'Node Type';
            app.NodeTypeDropDown = uidropdown(app.NodeCreationPanel);
            app.NodeTypeDropDown.Items = {'N', 'M', 'NM'};
            app.NodeTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @presetChange, true);
            app.NodeTypeDropDown.Value = 'N';
            app.NodePresetsDropDownLabel = uilabel(app.NodeCreationPanel);
            app.NodePresetsDropDownLabel.Text = 'Node Presets';
            app.NodePresetsDropDown = uidropdown(app.NodeCreationPanel);
            app.NodePresetsDropDown.Items = {'N/A'};
            app.NodePresetsDropDown.Value = 'N/A';
            app.SaveNodeButton = uibutton(app.NodeCreationPanel, 'push');
            app.SaveNodeButton.ButtonPushedFcn = createCallbackFcn(app, @saveNode, true);
            app.SaveNodeButton.Enable = 'off';
            app.SaveNodeButton.Text = 'Save Node';
            app.CancelButton_2 = uibutton(app.NodeCreationPanel, 'push');
            app.CancelButton_2.ButtonPushedFcn = createCallbackFcn(app, @cancelNodeSave, true);
            app.CancelButton_2.Enable = 'off';
            app.CancelButton_2.Text = 'Cancel';
            app.NodeNameEditFieldLabel = uilabel(app.NodeCreationPanel);
            app.NodeNameEditFieldLabel.Text = 'Node Name';
            app.NodeNameEditField = uieditfield(app.NodeCreationPanel, 'text');
            app.NodeDeletionPanel = uipanel(app.NodesTab);
            app.NodeDeletionPanel.Title = 'Node Deletion';
            app.SelectNodeButton = uibutton(app.NodeDeletionPanel, 'push');
            app.SelectNodeButton.ButtonPushedFcn = createCallbackFcn(app, @selectNodeDel, true);
            app.SelectNodeButton.Text = 'Select Node';
            app.CancelButton = uibutton(app.NodeDeletionPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelNodeDel, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Cancel';
            app.DeleteNodeButton = uibutton(app.NodeDeletionPanel, 'push');
            app.DeleteNodeButton.ButtonPushedFcn = createCallbackFcn(app, @deleteNode, true);
            app.DeleteNodeButton.Enable = 'off';
            app.DeleteNodeButton.Text = 'Delete Node';
            app.EditNodePanel = uipanel(app.NodesTab);
            app.EditNodePanel.Title = 'Edit Node';
            app.SelectNodeButton_2 = uibutton(app.EditNodePanel, 'push');
            app.SelectNodeButton_2.ButtonPushedFcn = createCallbackFcn(app, @selectNodeEdit, true);
            app.SelectNodeButton_2.Text = 'Select Node';
            app.AttributeDropDownLabel = uilabel(app.EditNodePanel);
            app.AttributeDropDownLabel.Text = 'Attribute';
            app.AttributeDropDown = uidropdown(app.EditNodePanel);
            app.AttributeDropDown.Items = {'N/A'};
            app.AttributeDropDown.ValueChangedFcn = createCallbackFcn(app, @editAttrChange, true);
            app.AttributeDropDown.Placeholder = 'N/A';
            app.AttributeDropDown.Value = 'N/A';
            app.UpdateButton = uibutton(app.EditNodePanel, 'push');
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.AttributeValueEditFieldLabel = uilabel(app.EditNodePanel);
            app.AttributeValueEditFieldLabel.Text = 'Attribute Value';
            app.AttributeValueEditField = uieditfield(app.EditNodePanel, 'numeric');
            app.AttributeValueEditField.ValueDisplayFormat = '%.3f';
            app.AttributeValueEditField.Placeholder = 'N/A';
            app.ResetButton = uibutton(app.EditNodePanel, 'push');
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.NodeNumberEditFieldLabel = uilabel(app.EditNodePanel);
            app.NodeNumberEditFieldLabel.Text = 'Node (Number)';
            app.NodeNumberEditField = uieditfield(app.EditNodePanel, 'text');
            app.NodeNumberEditField.Editable = 'off';
            app.NodeNumberEditField.Placeholder = '[No node selected]';
            app.PathsTab = uitab(app.TabGroup);
            app.PathsTab.Title = 'Paths';
            app.Button = uibutton(app.PathsTab, 'push');
            app.ProbesTab = uitab(app.TabGroup);
            app.ProbesTab.Title = 'Probes';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.NetworkTab = uitab(app.TabGroup2);
            app.NetworkTab.Title = 'Network';
            app.UIAxes = uiaxes(app.NetworkTab);
            xlabel(app.UIAxes, 'X (mm)')
            ylabel(app.UIAxes, 'Y (mm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 100];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SingleCellTab = uitab(app.TabGroup2);
            app.SingleCellTab.Title = 'Single Cell';
            app.UIAxes2 = uiaxes(app.SingleCellTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LoadandSavePanel = uipanel(app.UIFigure);
            app.LoadandSavePanel.Title = 'Load and Save';
            app.SelectNetworkConfigFileButton = uibutton(app.LoadandSavePanel, 'push');
            app.SelectNetworkConfigFileButton.ButtonPushedFcn = createCallbackFcn(app, @selectConfigFile, true);
            app.SelectNetworkConfigFileButton.Text = 'Select Network Config File';
            app.CurrentConfigFileEditFieldLabel = uilabel(app.LoadandSavePanel);
            app.CurrentConfigFileEditFieldLabel.Text = 'Current Config File';
            app.CurrentConfigFileEditField = uieditfield(app.LoadandSavePanel, 'text');
            app.CurrentConfigFileEditField.Editable = 'off';
            app.CurrentConfigFileEditField.Placeholder = '[No current file]';
            app.SaveNetworkandQuitButton = uibutton(app.LoadandSavePanel, 'push');
            app.SaveNetworkandQuitButton.ButtonPushedFcn = createCallbackFcn(app, @saveNetwork, true);
            app.SaveNetworkandQuitButton.Text = 'Save Network and Quit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorial_edit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end